classdef guiFOSFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        InputAudioFileNameEditField     matlab.ui.control.EditField
        InputAudioFileNameEditFieldLabel  matlab.ui.control.Label
        NotchFrequencyKnob              matlab.ui.control.Knob
        NotchFrequencyKnobLabel         matlab.ui.control.Label
        AudioOutputDeviceDropDown       matlab.ui.control.DropDown
        AudioOutputDeviceDropDownLabel  matlab.ui.control.Label
        useFilterSwitch                 matlab.ui.control.RockerSwitch
        useFilterSwitchLabel            matlab.ui.control.Label
        AudioDriverLabel                matlab.ui.control.Label
        AudioDriverDropDown             matlab.ui.control.DropDown
        PlayAudiothroughForthOrderFilterLabel  matlab.ui.control.Label
        PlayAudioButton                 matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
        FreqRespAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        num  % Numerator for filter 
        den  % Denominator for filter 
        
        FileIn % Audio file source object

        EQSetting % EQ bypass state  
        
        MyFilter % Filter Object      
        
        MyPortObj % UDP port object
        cFreq     % center frequency in Hz
        fs        % sampling frequency in Hz
        localIP   % IP address for local machine
        remoteIP  % IP address to send towards

        hLine     % handle to line object for plots
    end
    
    properties (Access = public)
        fileName            % audio input file name
        fileNameChangedFlag % flag indicates name has changed
    end
        
    methods (Access = private)
       
        function audioDevicesHelper(app)
                        
             % Populate the output audio device drop-down
            global newDrvList;
            global newDevices;
            
            if ~isempty(newDrvList)
                 app.AudioDriverDropDown.Items = strsplit(newDrvList,',');
            end             
            % Use get audioDevices method to identify local devices
            
            % Devices is a cell array of strings
            if ~isempty(newDevices)
                 app.AudioOutputDeviceDropDown.Items = strsplit(newDevices,',');
            end
              
        end
        
        % Note that calculation of the coefficients are the same as the one
        % used in the player side
        function updateFilterDesign(app,value)
            
            %Fs = app.FileIn.SampleRate;
            Fs = app.fs;
            
            % Redesign filter
            N = 4;
            gain = -12;
            centerFreq = value/(Fs/2);
                       
            bandwidth = 0.1;
            mode = "fos";
            [B,A] = designParamEQ(N,gain,centerFreq,bandwidth,mode,"Orientation","row");
            
            % Place calculated B and A in the myFilter numerator and
            % denominator
            app.MyFilter.Numerator = B;
            app.MyFilter.Denominator = A;
            
             % Plot frequency response
            [h,f] = freqz(B,A,512,Fs);
            plot(app.FreqRespAxes,f,abs(h));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global updateFlag;
            global newDataBlock;
            global newDrvList;
            global newDevices

             % Check if we're running on Mac or PC
            app.AudioDriverDropDown.Items = {'Not available'};
                
            % Populate the audio devices drop-down list
           audioDevicesHelper(app);
           
            % Load up an audio source file 
            app.fileNameChangedFlag = 0;
            app.fileName = 'FunkyDrums-44p1-stereo-25secs.mp3';
            app.fs = 44100.0;  % assume a value for now            

            % Init filter object
            app.MyFilter = dsp.FourthOrderSectionFilter;
            
            % Initialize paramEQ
            app.cFreq = 1000.0;
            updateFilterDesign(app,app.cFreq);
            
            % Set EQ to bypass
            app.useFilterSwitch.Value = 'On';
            app.EQSetting = 1;
            
            % setup UDP port obj
%            app.localIP = "3.236.126.157";
%            app.remoteIP = "52.95.4.6";
            app.localIP = "127.0.0.1";
            app.remoteIP = "127.0.0.1";
            
            app.MyPortObj = udpport("datagram","IPV4","LocalHost",app.localIP,"LocalPort", 3031);
            configureCallback(app.MyPortObj,"datagram",1,@receivedDatGram);

            % indicate start using data received from UDP
            updateFlag = 0;
            assignin('base','updateFlag',updateFlag);
                        
            % setup buffer to hold display data
            newDataBlock = zeros(1024,1);
            
            % set drv list to null
            newDrvList = [];
            newDevices = [];
            
            % sending the file name to the player side, upon receiving the
            % filename the receiver will send the driver selection list
            txtcommand = 'newname';
            outStr = strcat(txtcommand, app.fileName);
            write(app.MyPortObj, outStr, "char", app.remoteIP, 3030);
            
            % sending the center freq to player side
            write(app.MyPortObj, app.cFreq,"single",app.remoteIP,3030);
            
           % Populate the drop-down list
           audioDevicesHelper(app);

           % prepare ploting handle
           app.hLine = line(app.UIAxes, ...
                'XData',[], ...
                'YData',[], ...
                'Color', 'black', ...
                'Marker','none');
            app.hLine.XData = 1:1024;
            app.hLine.YData = zeros(1,1024);
            axis(app.UIAxes, [1 1024 -1 1] );

        end

        % Value changed function: PlayAudioButton
        function PlayAudioPlayAudioButtonPushed(app, event)

            global updateFlag;
            global newDataBlock;

            value = app.PlayAudioButton.Value;
            
            if(value)
                
                if app.fileNameChangedFlag==1
                    updateFilterDesign(app, app.cFreq);
                    app.fileNameChangedFlag = 0;
                end
                
                txtcommand = 'start';
                write(app.MyPortObj, txtcommand,"char", app.remoteIP, 3030);
                
                app.PlayAudioButton.Text = 'Playing...';


                pause(0.023); % UDP frameSize/Fs = 1024/44100 = 0.0232;
                
                % Start streaming loop
                while(app.PlayAudioButton.Value)

                    pause('on');
                                                           
                    if updateFlag == 1
                       y = newDataBlock;
                    else
                        y = zeros(1024,1);
                    end
                    
                    set(app.hLine,'YData', y);
                    
                    drawnow limitrate;
                end
                txtcommand = 'stopp';
                write(app.MyPortObj, txtcommand, "char", app.remoteIP, 3030);

                app.PlayAudioButton.Text = 'Play Audio';
               
            end
            txtcommand = 'stopp';
            write(app.MyPortObj, txtcommand, "char", app.remoteIP, 3030);

        end

        % Value changed function: useFilterSwitch
        function useFilterSwitchValueChanged(app, event)
            value = app.useFilterSwitch.Value;
            app.EQSetting = strcmp(value,'On');
            
            txtcommand = strcat('effect',value);
            write(app.MyPortObj, txtcommand, "char", app.remoteIP, 3030);

        end

        % Value changed function: AudioDriverDropDown
        function AudioDriverDropDownValueChanged(app, event)

            % Populate the audio devices drop-down list
           audioDevicesHelper(app);
           value = app.AudioDriverDropDown.Value;
           
           txtcommand = 'drvSel';
           outStr = strcat(txtcommand, (value));
           write(app.MyPortObj, outStr, "char", app.remoteIP, 3030);

        end

        % Value changed function: NotchFrequencyKnob
        function NotchFrequencyKnobValueChanged(app, event)
            value = app.NotchFrequencyKnob.Value;
            
            write(app.MyPortObj, value, "single", app.remoteIP, 3030);
                        
            updateFilterDesign(app,value);
        end

        % Value changed function: InputAudioFileNameEditField
        function InputAudioFileNameEditFieldValueChanged(app, event)
            value = app.InputAudioFileNameEditField.Value;
            app.fileName = value;
            app.fileNameChangedFlag = 1; 
            
            % send name to player side
            txtcommand = 'newname';
            outStr = strcat(txtcommand, app.fileName);
            write(app.MyPortObj, outStr, "char", app.remoteIP, 3030);

        end

        % Value changed function: AudioOutputDeviceDropDown
        function AudioOutputDeviceDropDownValueChanged(app, event)

           % Populate the audio devices drop-down list
           audioDevicesHelper(app);
           value = app.AudioOutputDeviceDropDown.Value;
           
           txtcommand = 'OutDevSel';
           outStr = strcat(txtcommand, (value));
           write(app.MyPortObj, outStr, "char", app.remoteIP, 3030);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FreqRespAxes = uiaxes(app.GridLayout);
            title(app.FreqRespAxes, 'Frequency Response')
            xlabel(app.FreqRespAxes, 'Frequency')
            ylabel(app.FreqRespAxes, 'Y')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Audio signal')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Y')
            app.PlayAudioButton = uibutton(app.GridLayout, 'state');
            app.PlayAudioButton.ValueChangedFcn = createCallbackFcn(app, @PlayAudioPlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio!';
            app.PlayAudiothroughForthOrderFilterLabel = uilabel(app.GridLayout);
            app.PlayAudiothroughForthOrderFilterLabel.Text = 'Play Audio through Forth Order Filter';
            app.AudioDriverDropDown = uidropdown(app.GridLayout);
            app.AudioDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioDriverDropDownValueChanged, true);
            app.AudioDriverLabel = uilabel(app.GridLayout);
            app.AudioDriverLabel.Text = 'Audio Driver';
            app.useFilterSwitchLabel = uilabel(app.GridLayout);
            app.useFilterSwitchLabel.Text = 'use Filter';
            app.useFilterSwitch = uiswitch(app.GridLayout, 'rocker');
            app.useFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @useFilterSwitchValueChanged, true);
            app.AudioOutputDeviceDropDownLabel = uilabel(app.GridLayout);
            app.AudioOutputDeviceDropDownLabel.Text = 'Audio Output Device';
            app.AudioOutputDeviceDropDown = uidropdown(app.GridLayout);
            app.AudioOutputDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioOutputDeviceDropDownValueChanged, true);
            app.NotchFrequencyKnobLabel = uilabel(app.GridLayout);
            app.NotchFrequencyKnobLabel.Text = 'Notch Frequency';
            app.NotchFrequencyKnob = uiknob(app.GridLayout, 'continuous');
            app.NotchFrequencyKnob.Limits = [20 20000];
            app.NotchFrequencyKnob.MajorTicks = [20 4000 8000 12000 16000 20000];
            app.NotchFrequencyKnob.MajorTickLabels = {'20', '4000', '8000', '12000', '16000', '20000', ''};
            app.NotchFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @NotchFrequencyKnobValueChanged, true);
            app.NotchFrequencyKnob.Value = 1000;
            app.InputAudioFileNameEditFieldLabel = uilabel(app.GridLayout);
            app.InputAudioFileNameEditFieldLabel.Text = 'Input Audio File Name';
            app.InputAudioFileNameEditField = uieditfield(app.GridLayout, 'text');
            app.InputAudioFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @InputAudioFileNameEditFieldValueChanged, true);
            app.InputAudioFileNameEditField.Value = 'FunkyDrums-44p1-stereo-25secs.mp3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiFOSFilterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end